classdef mpcanalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes_vx             matlab.ui.control.UIAxes
        UIAxes_d              matlab.ui.control.UIAxes
        UIAxes_dd             matlab.ui.control.UIAxes
        UIAxes_ax             matlab.ui.control.UIAxes
        UIAxes_ay             matlab.ui.control.UIAxes
        UIAxes_tire1          matlab.ui.control.UIAxes
        IDSliderLabel         matlab.ui.control.Label
        IDSlider              matlab.ui.control.Slider
        LoadDatasetButton     matlab.ui.control.Button
        DatasetLabel          matlab.ui.control.Label
        NextButton            matlab.ui.control.Button
        PreviousButton        matlab.ui.control.Button
        UIAxes_solver_time    matlab.ui.control.UIAxes
        UIAxes_solver_iter    matlab.ui.control.UIAxes
        UIAxes_solver_status  matlab.ui.control.UIAxes
        UIAxes_cost           matlab.ui.control.UIAxes
        UIAxes_tire2          matlab.ui.control.UIAxes
        CurrentIdx            matlab.ui.control.Label
        UIAxes_gg             matlab.ui.control.UIAxes
        CurrentID             matlab.ui.control.Label
    end

    
    properties (Access = private)
        debug
        debug_slow
        dataset_name
        sys
        P_VDC_VirtualController
        P_VDC_PositiveAxLimScale
        dist
        handles_vx
        handles_d
        handles_dd
        handles_ax
        handles_ay
        handles_tire1
        handles_tire2
        handles_solver_status
        handles_solver_iter
        handles_solver_time
        handles_cost
        handles_gg
    end
    
    methods (Access = private)
        
        function initialize_visz(app)
            % obtain parameters 
            sysDict = Simulink.data.dictionary.open('il_mvdc_mpc.sldd');
            dDataSectObj2 = getSection(sysDict, 'Design Data');
            % load controller parameters from data dictionary
            app.sys = getValue(getEntry(dDataSectObj2, 'sys'));
            help = getValue(getEntry(dDataSectObj2, 'P_VDC_VirtualController'));
            app.P_VDC_VirtualController = help.Value;
            help = getValue(getEntry(dDataSectObj2, 'P_VDC_PositiveAxLimScale'));
            app.P_VDC_PositiveAxLimScale = help.Value;
            
            % load data 
            [v_target, d_target, dot_d_target, v_pred, tire_util_1, tire_util_2, tire_util_pred_1, tire_util_pred_2, ...
                tPred, p_t_2, p_t_3, M_s, be_u_abs, be_l_abs, ax_lim_mps2, ay_lim_mps2, ...
                d_lim_ub_m, d_lim_lb_m, vx_lin_mps] ...
                = prepareTMPCPlotting(app.debug, app.debug_slow, 1, app.sys, app.P_VDC_VirtualController, app.P_VDC_PositiveAxLimScale); 
            % bring error states into separate timeseries for plotting
            errorState_2 = timeseries(app.debug_slow.debug_slow_error_state.Data(:, 2), ...
                app.debug_slow.debug_slow_error_state.Time);
            errorState_3 = timeseries(app.debug_slow.debug_slow_error_state.Data(:, 3), ...
                app.debug_slow.debug_slow_error_state.Time);

                
            % setup dynamic system plots
            hold(app.UIAxes_vx, 'on'); 
            hold(app.UIAxes_d, 'on'); 
            hold(app.UIAxes_dd, 'on'); 
            hold(app.UIAxes_ax, 'on'); 
            hold(app.UIAxes_ay, 'on'); 
            hold(app.UIAxes_tire1, 'on'); 
            hold(app.UIAxes_tire2, 'on'); 
            hold(app.UIAxes_solver_status, 'on'); 
            hold(app.UIAxes_solver_iter, 'on'); 
            hold(app.UIAxes_solver_time, 'on'); 
            hold(app.UIAxes_cost, 'on'); 
            hold(app.UIAxes_gg, 'on'); 
            axis(app.UIAxes_gg, 'equal'); 
            
            app.handles_vx = plotTMPCPrediction(app.UIAxes_vx, tPred, app.debug.debug_mvdc_state_estimation_debug_StateEstimate_v_mps, true, v_pred, M_s, be_l_abs(1), be_u_abs(1), ...
                               app.P_VDC_VirtualController, false, inf, inf, repmat([1; 0; 0], 1, app.sys.N_hor+1), false, true, v_target, true, vx_lin_mps); 
            app.handles_d = plotTMPCPrediction(app.UIAxes_d, tPred, errorState_2, true, p_t_2, M_s, be_l_abs(2), be_u_abs(2), ...
                               app.P_VDC_VirtualController, true, d_lim_ub_m, d_lim_lb_m, repmat([0; 1; 0], 1,  app.sys.N_hor+1), false, true, d_target, false, 0); 
            app.handles_dd = plotTMPCPrediction(app.UIAxes_dd, tPred, errorState_3, true, p_t_3, M_s, be_l_abs(3), be_u_abs(3), ...
                               app.P_VDC_VirtualController, false, inf, inf, repmat([0; 0; 1], 1,  app.sys.N_hor+1), false, true, dot_d_target, false, 0); 
            app.handles_ax = plotTMPCPrediction(app.UIAxes_ax, tPred, app.debug.debug_mvdc_state_estimation_debug_StateEstimate_ax_mps2, ...
                               true, app.debug_slow.debug_slow_ax_pred_mps2.Data(1, :)', M_s, NaN, NaN, app.P_VDC_VirtualController, ...
                               true, ax_lim_mps2, -ax_lim_mps2, repmat([1; 0], 1, app.sys.N_hor+1), true, false, 0, false, 0); 
            app.handles_ay = plotTMPCPrediction(app.UIAxes_ay, tPred, app.debug.debug_mvdc_state_estimation_debug_StateEstimate_ay_mps2, ...
                               true, app.debug_slow.debug_slow_ay_pred_mps2.Data(1, :)', M_s, NaN, NaN, app.P_VDC_VirtualController, ...
                               true, ay_lim_mps2, -ay_lim_mps2, repmat([0; 1], 1, app.sys.N_hor+1), true, false, 0, false, 0); 
            app.handles_tire1 = plotTMPCPrediction(app.UIAxes_tire1, tPred, tire_util_1, true, tire_util_pred_1, M_s, NaN, NaN, app.P_VDC_VirtualController, true, ...
                               ones(app.sys.N_hor+1, 1), -ones(app.sys.N_hor+1, 1), [1./ax_lim_mps2; 1./ay_lim_mps2], true, false, 0, false, 0); 
            app.handles_tire2 = plotTMPCPrediction(app.UIAxes_tire2, tPred, tire_util_2, true, tire_util_pred_2, M_s, NaN, NaN, app.P_VDC_VirtualController, true, ...
                               ones(app.sys.N_hor+1, 1), -ones(app.sys.N_hor+1, 1), [1./ax_lim_mps2; -1./ay_lim_mps2], true, false, 0, false, 0); 
            % setup debug information plots 
            app.handles_solver_status.signal = plot(app.UIAxes_solver_status, app.debug_slow.debug_slow_solver_state.Time, app.debug_slow.debug_slow_solver_state.Data); 
            app.handles_solver_status.marker = plot(app.UIAxes_solver_status, app.debug_slow.debug_slow_solver_state.Time(1), app.debug_slow.debug_slow_solver_state.Data(1), 'ro', 'MarkerFaceColor', 'r'); 
            app.handles_solver_iter.signal = plot(app.UIAxes_solver_iter, app.debug_slow.debug_slow_solver_iteration.Time, app.debug_slow.debug_slow_solver_iteration.Data); 
            app.handles_solver_iter.marker = plot(app.UIAxes_solver_iter, app.debug_slow.debug_slow_solver_iteration.Time(1), app.debug_slow.debug_slow_solver_iteration.Data(1), 'ro', 'MarkerFaceColor', 'r');  
            app.handles_solver_time.signal = plot(app.UIAxes_solver_time, app.debug_slow.debug_slow_solver_solveTime_s.Time, app.debug_slow.debug_slow_solver_solveTime_s.Data); 
            app.handles_solver_time.marker = plot(app.UIAxes_solver_time, app.debug_slow.debug_slow_solver_solveTime_s.Time(1), app.debug_slow.debug_slow_solver_solveTime_s.Data(1), 'ro', 'MarkerFaceColor', 'r'); 
            % add target velocity profile plot
            app.handles_vx.global_target = plot(app.UIAxes_vx, app.debug.debug_mvdc_path_matching_debug_ActualTrajPoint_v_mps.Time, app.debug.debug_mvdc_path_matching_debug_ActualTrajPoint_v_mps.Data, 'r--', 'DisplayName', 'Global target'); 
            
            % add cost value plots 
            app.handles_cost.states = plot(app.UIAxes_cost, app.debug_slow.debug_slow_cost_values.Time, app.debug_slow.debug_slow_cost_values.Data(:, 1)); 
            app.handles_cost.inputs = plot(app.UIAxes_cost, app.debug_slow.debug_slow_cost_values.Time, app.debug_slow.debug_slow_cost_values.Data(:, 2)); 
            app.handles_cost.reg = plot(app.UIAxes_cost, app.debug_slow.debug_slow_cost_values.Time, app.debug_slow.debug_slow_cost_values.Data(:, 3)); 
            app.handles_cost.slack = plot(app.UIAxes_cost, app.debug_slow.debug_slow_cost_values.Time, app.debug_slow.debug_slow_cost_values.Data(:, 4)); 
            app.handles_cost.marker = plot(app.UIAxes_cost, app.debug_slow.debug_slow_solver_solveTime_s.Time(1), 0, 'ro', 'MarkerFaceColor', 'r'); 
            legend(app.UIAxes_cost, 'States', 'Inputs', 'Reg', 'Slacks'); 
            
            % add gg diagram plot
            app.handles_gg.lim1 = plot(app.UIAxes_gg, [-1, 0], [0, 1], 'r--'); 
            app.handles_gg.lim2 = plot(app.UIAxes_gg, [1, 0], [0, 1], 'r--'); 
            app.handles_gg.lim3 = plot(app.UIAxes_gg, [-1, 0], [0, -1], 'r--'); 
            app.handles_gg.lim4 = plot(app.UIAxes_gg, [1, 0], [0, -1], 'r--'); 
            app.handles_gg.pred = plot(app.UIAxes_gg, app.debug_slow.debug_slow_ay_pred_mps2.Data(1, :)./ay_lim_mps2, ...
                app.debug_slow.debug_slow_ax_pred_mps2.Data(1, :)./ax_lim_mps2, ...
                '*', 'Color', [0.8902, 0.4471, 0.1333]); 
            app.handles_gg.ellipsoids = {}; 
            j = 1; 
            t = linspace(0, 2*pi, 50); % or any high number to make curve smooth
            z = [cos(t); sin(t)];
            for i = 1:10:(app.sys.N_hor+1)
                 % extract input uncertainty 
                 E = app.P_VDC_VirtualController*M_s(:, (3*(i-1)+1):(3*(i-1)+3))*app.P_VDC_VirtualController'; 
                 % plots an ellipse of the form xE^-1x = 1
                 R = chol(inv(E));
                 ellipse = inv(R) * z;
                 app.handles_gg.ellipsoids{j} = plot(app.UIAxes_gg, ...
                     (ellipse(2,:) + app.debug_slow.debug_slow_ay_pred_mps2.Data(1, i))/ay_lim_mps2(i), ...
                     (ellipse(1,:) + app.debug_slow.debug_slow_ax_pred_mps2.Data(1, i))/ax_lim_mps2(i), 'k--'); 
                 j = j + 1; 
            end
            
            % set current idx 
            app.CurrentIdx.Text = ['Current Idx: ', num2str(1)]; 
            % set current idx 
            traj_id = app.debug_slow.debug_slow_tartraj_TrajCnt.Data(1); 
            app.CurrentID.Text = ['Current Trajectory ID: ', num2str(traj_id)]; 
            
        end
    
        function update_visz(app, tmpc_cnt)
            % load data 
            disp(['Update plot to idx: ' num2str(tmpc_cnt)]); 
            [v_target, d_target, dot_d_target, v_pred, ~, ~, tire_util_pred_1, tire_util_pred_2, ...
                tPred, p_t_2, p_t_3, M_s, be_u_abs, be_l_abs, ax_lim_mps2, ay_lim_mps2, ...
                d_lim_ub_m, d_lim_lb_m, vx_lin_mps] = prepareTMPCPlotting(app.debug, app.debug_slow, tmpc_cnt, app.sys, app.P_VDC_VirtualController, app.P_VDC_PositiveAxLimScale); 
            update_plotTMPCPrediction(app.UIAxes_vx, app.handles_vx, tPred, true, v_pred, M_s, be_l_abs(1), be_u_abs(1), ...
                               app.P_VDC_VirtualController, false, inf, inf, repmat([1; 0; 0], 1, app.sys.N_hor+1), false, true, v_target, true, vx_lin_mps); 
            update_plotTMPCPrediction(app.UIAxes_d, app.handles_d, tPred, true, p_t_2, M_s, be_l_abs(2), be_u_abs(2), ...
                               app.P_VDC_VirtualController, true, d_lim_ub_m, d_lim_lb_m, repmat([0; 1; 0], 1, app.sys.N_hor+1), false, true, d_target, false, 0); 
            update_plotTMPCPrediction(app.UIAxes_dd, app.handles_dd, tPred, true, p_t_3, M_s, be_l_abs(3), be_u_abs(3), ...
                               app.P_VDC_VirtualController, false, inf, inf, repmat([0; 0; 1], 1, app.sys.N_hor+1), false, true, dot_d_target, false, 0); 
            update_plotTMPCPrediction(app.UIAxes_ax, app.handles_ax, tPred, ...
                               true, app.debug_slow.debug_slow_ax_pred_mps2.Data(tmpc_cnt, :)', M_s, NaN, NaN, app.P_VDC_VirtualController, true, ...
                               ax_lim_mps2, -ax_lim_mps2, repmat([1; 0], 1, app.sys.N_hor+1), true, false, 0, false, 0); 
            update_plotTMPCPrediction(app.UIAxes_ay, app.handles_ay, tPred, ...
                               true, app.debug_slow.debug_slow_ay_pred_mps2.Data(tmpc_cnt, :)', M_s, NaN, NaN, app.P_VDC_VirtualController, true, ...
                               ay_lim_mps2, -ay_lim_mps2, repmat([0; 1], 1, app.sys.N_hor+1), true, false, 0, false, 0); 
            update_plotTMPCPrediction(app.UIAxes_tire1, app.handles_tire1, tPred, true, tire_util_pred_1, M_s, NaN, NaN, app.P_VDC_VirtualController, true, ...
                               ones(app.sys.N_hor+1, 1), -ones(app.sys.N_hor+1, 1), [1./ax_lim_mps2; 1./ay_lim_mps2], true, false, 0, false, 0); 
            update_plotTMPCPrediction(app.UIAxes_tire2, app.handles_tire2, tPred, true, tire_util_pred_2, M_s, NaN, NaN, app.P_VDC_VirtualController, true, ...
                               ones(app.sys.N_hor+1, 1), -ones(app.sys.N_hor+1, 1), [1./ax_lim_mps2; -1./ay_lim_mps2], true, false, 0, false, 0); 
                           
            % setup debug information plots 
            xlim(app.UIAxes_solver_status, [tPred(1) - 3, tPred(1) + 5]); 
            xlim(app.UIAxes_solver_iter, [tPred(1) - 3, tPred(1) + 5]); 
            xlim(app.UIAxes_solver_time, [tPred(1) - 3, tPred(1) + 5]); 
            xlim(app.UIAxes_cost, [tPred(1) - 3, tPred(1) + 5]); 
            set(app.handles_solver_status.marker, 'XData', app.debug_slow.debug_slow_solver_state.Time(tmpc_cnt), 'YData', app.debug_slow.debug_slow_solver_state.Data(tmpc_cnt)); 
            set(app.handles_solver_iter.marker, 'XData', app.debug_slow.debug_slow_solver_iteration.Time(tmpc_cnt), 'YData', app.debug_slow.debug_slow_solver_iteration.Data(tmpc_cnt)); 
            set(app.handles_solver_time.marker, 'XData', app.debug_slow.debug_slow_solver_solveTime_s.Time(tmpc_cnt), 'YData', app.debug_slow.debug_slow_solver_solveTime_s.Data(tmpc_cnt)); 
            set(app.handles_cost.marker, 'XData', app.debug_slow.debug_slow_solver_solveTime_s.Time(tmpc_cnt)); 
            
            % extract required snippts from measurement data 
            idx_tmpc_cnt = find(app.debug_slow.debug_slow_tmpc_cnt.Data == tmpc_cnt, 1); 
            traj_id = app.debug_slow.debug_slow_tartraj_TrajCnt.Data(idx_tmpc_cnt); 
            set(app.handles_gg.pred, 'XData', app.debug_slow.debug_slow_ay_pred_mps2.Data(tmpc_cnt, :)./ay_lim_mps2, ...
                'YData', app.debug_slow.debug_slow_ax_tire_pred_mps2.Data(tmpc_cnt, :)./ax_lim_mps2); 

            j = 1; 
            t = linspace(0, 2*pi, 50); % or any high number to make curve smooth
            z = [cos(t); sin(t)];
            for i = 1:10:(app.sys.N_hor+1)
                 % extract input uncertainty 
                 E = app.P_VDC_VirtualController*M_s(:, (3*(i-1)+1):(3*(i-1)+3))*app.P_VDC_VirtualController'; 
                 % plots an ellipse of the form xE^-1x = 1
                 R = chol(inv(E));
                 ellipse = inv(R) * z;
                 set(app.handles_gg.ellipsoids{j}, 'XData', (ellipse(2,:) + app.debug_slow.debug_slow_ay_pred_mps2.Data(tmpc_cnt, i))/ay_lim_mps2(i), ...
                     'YData', (ellipse(1,:) + app.debug_slow.debug_slow_ax_tire_pred_mps2.Data(tmpc_cnt, i))/ax_lim_mps2(i)); 
                 j = j + 1; 
            end
                        
            % set current idx 
            app.CurrentIdx.Text = ['Current TMPC Idx: ', num2str(tmpc_cnt)]; 
            % set current idx 
            app.CurrentID.Text = ['Current Trajectory ID: ', num2str(traj_id)]; 
        end
    end
    

    methods (Access = private)

        % Button pushed function: LoadDatasetButton
        function LoadDatasetButtonPushed(app, event)
            % load dataset
            [FileName,PathName] = uigetfile('*.mat','Select the debug data file');
            if((FileName == 0))
              disp('No file selected'); 
              return
            end
            
            disp('Loading file');
            data = load([PathName FileName]);
            
            if(isfield(data, 'debug') && isfield(data, 'debug_slow'))
                disp('Valid dataset found'); 
                % store everything in app
                app.debug = data.debug;
                app.debug_slow = data.debug_slow; 
                app.dataset_name = FileName; 
                % setup GUI for visualization
                app.DatasetLabel.Text = FileName;
                idx_min = double(1); 
                idx_max = double(length(app.debug_slow.debug_slow_tmpc_cnt.Data));
                app.IDSlider.Limits = [idx_min, idx_max];
                app.initialize_visz();
            else
            disp('No data found in file'); 
                return; 
            end
           
        end

        % Callback function: IDSlider, IDSlider
        function IDSliderValueChanged(app, event)
            value = app.IDSlider.Value;
            update_visz(app, floor(value));
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.IDSlider.Value = app.IDSlider.Value + 1; 
            update_visz(app, floor(app.IDSlider.Value));
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            app.IDSlider.Value = app.IDSlider.Value - 1; 
            update_visz(app, floor(app.IDSlider.Value));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes_vx = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_vx, 'vx in mps')
            app.UIAxes_d = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_d, 'd in m')
            app.UIAxes_dd = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_dd, 'dot_d in m')
            app.UIAxes_ax = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_ax, 'ax in mps2')
            app.UIAxes_ay = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_ay, 'ay in mps2')
            app.UIAxes_tire1 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_tire1, 'tire1')
            app.IDSliderLabel = uilabel(app.UIFigure);
            app.IDSliderLabel.Text = 'ID';
            app.IDSlider = uislider(app.UIFigure);
            app.IDSlider.ValueChangedFcn = createCallbackFcn(app, @IDSliderValueChanged, true);
            app.IDSlider.ValueChangingFcn = createCallbackFcn(app, @IDSliderValueChanged, true);
            app.LoadDatasetButton = uibutton(app.UIFigure, 'push');
            app.LoadDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatasetButtonPushed, true);
            app.LoadDatasetButton.Text = 'Load Dataset';
            app.DatasetLabel = uilabel(app.UIFigure);
            app.DatasetLabel.Text = '';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.UIAxes_solver_time = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_solver_time, 'solver time in s')
            app.UIAxes_solver_iter = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_solver_iter, 'solver iterations')
            app.UIAxes_solver_status = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_solver_status, 'solver status')
            app.UIAxes_cost = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_cost, 'cost')
            app.UIAxes_tire2 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_tire2, 'tire2')
            app.CurrentIdx = uilabel(app.UIFigure);
            app.CurrentIdx.Text = 'Current TMPC Idx: ';
            app.UIAxes_gg = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_gg, 'ay normalized')
            ylabel(app.UIAxes_gg, 'ax normalized')
            app.CurrentID = uilabel(app.UIFigure);
            app.CurrentID.Text = 'Current Trajectory ID: ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mpcanalyzer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end